classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Credit2                         matlab.ui.control.Label
        Credit1                         matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        TempField                       matlab.ui.control.NumericEditField
        TempCLabel                      matlab.ui.control.Label
        SPO2Field                       matlab.ui.control.NumericEditField
        SpOLabel                        matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        MLX90614Lamp                    matlab.ui.control.Lamp
        MLX90614SensorTemperatureLabel  matlab.ui.control.Label
        MAX30102Lamp                    matlab.ui.control.Lamp
        MAX30102Label                   matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        tcpServer   % tcpserver object for ESP32
        LastMsgTime     % datetime of last successful read
        ConnTimer       % MATLAB timer object that checks connection
    end
    
    methods (Access = private)
        
        function updateReadings(app, src)
            try
                app.LastMsgTime = datetime('now');

                data   = readline(src);                    % get one line
                tokens = split(strtrim(data), {',','='});  % split at commas & equals

                % assume both sensors are OK if any data arrives
                app.MAX30102Lamp.Color = [0 1 0];
                app.MLX90614Lamp.Color = [0 1 0];

                switch tokens{2}
                    case "NoFinger"
                        app.StatusLabel.Text = "No Finger Detected";
                        app.SPO2Field.Value  = 0;
                        app.TempField.Value  = 0;

                    case "Stabilizing"
                        app.StatusLabel.Text = "Finger Detected. Stabilizing...";

                    case "Data"
                        app.StatusLabel.Text = "Reading OK";
                        app.SPO2Field.Value  = str2double(tokens{4});
                        app.TempField.Value  = str2double(tokens{6});

                    otherwise
                        app.StatusLabel.Text  = "Unknown response";
                        app.MAX30102Lamp.Color = [1 0 0];
                        app.MLX90614Lamp.Color = [1 0 0];
                end
            catch ME
                app.StatusLabel.Text = "Parse error";
                disp(ME.message);
            end
        end


        function checkConnection(app)
            % how many seconds since last valid data?
            dt = seconds(datetime('now') - app.LastMsgTime);
            if dt > 12.5
                % connection lost!
                app.StatusLabel.Text   = "Connection Lost";
                app.MAX30102Lamp.Color = [1 0 0];
                app.MLX90614Lamp.Color = [1 0 0];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % disable the button
            app.StartButton.Enable = "off";
            try
                % start TCP server
                app.tcpServer = tcpserver("0.0.0.0", 5050, "Timeout", 10);
                % register updateReadings for each incoming line (terminated by \n)
                configureCallback(app.tcpServer, "terminator", @(src,~) updateReadings(app, src));
                app.StatusLabel.Text = "Server running – waiting for ESP32...";

                app.LastMsgTime = datetime('now');
                app.ConnTimer = timer(...
                    'ExecutionMode','fixedSpacing', ...
                    'Period',1, ...
                    'TimerFcn', @(~,~) checkConnection(app) ...
                );

                start(app.ConnTimer);

            catch ME
                app.StatusLabel.Text = "Failed to start server";
                disp(ME.message);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % STOP & DELETE the timer
            if ~isempty(app.ConnTimer) && isvalid(app.ConnTimer)
                stop(app.ConnTimer);
                delete(app.ConnTimer);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MAX30102Label = uilabel(app.UIFigure);
            app.MAX30102Label.Text = 'MAX30102 Sensor (SpO₂)';
            app.MAX30102Lamp = uilamp(app.UIFigure);
            app.MLX90614SensorTemperatureLabel = uilabel(app.UIFigure);
            app.MLX90614SensorTemperatureLabel.Text = 'MLX90614 Sensor (Temperature)';
            app.MLX90614Lamp = uilamp(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Waiting to start...';
            app.SpOLabel = uilabel(app.UIFigure);
            app.SpOLabel.Text = 'SpO₂ (%)';
            app.SPO2Field = uieditfield(app.UIFigure, 'numeric');
            app.TempCLabel = uilabel(app.UIFigure);
            app.TempCLabel.Text = 'Temp (°C)';
            app.TempField = uieditfield(app.UIFigure, 'numeric');
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Wireless SpO₂ and Temperature Monitoring';
            app.Credit1 = uilabel(app.UIFigure);
            app.Credit1.Text = 'Designed and Developed by';
            app.Credit2 = uilabel(app.UIFigure);
            app.Credit2.Text = 'Shamanthak Reddy Mallu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end